classdef View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gui                             matlab.ui.Figure
        HelpButton                      matlab.ui.control.Button
        OutputFilenamePanel             matlab.ui.container.Panel
        SelectOutputFilenameBtn         matlab.ui.control.Button
        OutputFilename                  matlab.ui.control.EditField
        ExcelfilesettingsPanel          matlab.ui.container.Panel
        TableindexfieldLabel_2          matlab.ui.control.Label
        PriceTypeIndexField             matlab.ui.control.DropDown
        DatastartingcellEditFieldLabel  matlab.ui.control.Label
        DataStartingCell                matlab.ui.control.EditField
        HeaderstartingcellEditFieldLabel  matlab.ui.control.Label
        HeaderStartingCell              matlab.ui.control.EditField
        StartProcessingButton           matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        SelectFile                      matlab.ui.container.Panel
        ListOfInvoices                  matlab.ui.control.ListBox
        SelectFilesBtn                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        winController % controller of the view
    end
    
    methods (Access = public)
        function results = publicFunctionTest(app)
            disp('public function test')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, winController)
            app.winController = winController;
        end

        % Callback function: CloseButton, gui
        function guiCloseRequest(app, event)
            app.winController.closeWindow();
            delete(app)
        end

        % Button pushed function: SelectFilesBtn
        function SelectFilesBtn_Callback(app, event)
            app.winController.SelectFilesBtn_Callback();
        end

        % Callback function
        function InputFilenameValueChanged(app, event)
            value = app.InputFilename.Value;
            app.winController.updateFilename(value);
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            app.winController.StartProcessing();
        end

        % Value changed function: DataStartingCell, HeaderStartingCell, 
        % PriceTypeIndexField
        function updateSettings(app, event)
            app.winController.updateSettings(event);
        end

        % Button pushed function: SelectOutputFilenameBtn
        function SelectOutputFilenameBtnButtonPushed(app, event)
            [selfile, path] = uiputfile({'*.xls;*.xlsx','Excel files (*.xls, *.xlsx)'; ...
                                     '*.*',  'All Files (*.*)'}, ...
                                     'Select output filename', app.OutputFilename.Value);
            if selfile == 0; return; end
            app.OutputFilename.Value = fullfile(path, selfile);
            app.OutputFilenameValueChanged(event);
        end

        % Value changed function: OutputFilename
        function OutputFilenameValueChanged(app, event)
            value = app.OutputFilename.Value;
            app.winController.updateOutputFilename(value);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('https://github.com/Ajaxels/OpenIris-SplitTheBill/wiki', '-helpbrowser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gui = uifigure('Visible', 'off');
            app.gui.Name = 'OpenIris Stats';
            app.gui.CloseRequestFcn = createCallbackFcn(app, @guiCloseRequest, true);
            app.SelectFile = uipanel(app.gui);
            app.SelectFile.Title = 'Select excel files with invoices...';
            app.SelectFilesBtn = uibutton(app.SelectFile, 'push');
            app.SelectFilesBtn.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesBtn_Callback, true);
            app.SelectFilesBtn.Tooltip = {'press to select Excel file with invoice information'; ''; ''};
            app.SelectFilesBtn.Text = '...';
            app.ListOfInvoices = uilistbox(app.SelectFile);
            app.ListOfInvoices.Items = {};
            app.ListOfInvoices.Value = {};
            app.CloseButton = uibutton(app.gui, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @guiCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.StartProcessingButton = uibutton(app.gui, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'Start processing';
            app.ExcelfilesettingsPanel = uipanel(app.gui);
            app.ExcelfilesettingsPanel.Title = 'Excel file settings';
            app.HeaderStartingCell = uieditfield(app.ExcelfilesettingsPanel, 'text');
            app.HeaderStartingCell.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.HeaderStartingCell.Tooltip = {'Index of the 1st header row cell'};
            app.HeaderStartingCell.Value = 'A3';
            app.HeaderstartingcellEditFieldLabel = uilabel(app.ExcelfilesettingsPanel);
            app.HeaderstartingcellEditFieldLabel.Text = 'Header starting cell';
            app.DataStartingCell = uieditfield(app.ExcelfilesettingsPanel, 'text');
            app.DataStartingCell.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.DataStartingCell.Tooltip = {'Index of the 1st cell containing charges'};
            app.DataStartingCell.Value = 'A4';
            app.DatastartingcellEditFieldLabel = uilabel(app.ExcelfilesettingsPanel);
            app.DatastartingcellEditFieldLabel.Text = 'Data starting cell';
            app.PriceTypeIndexField = uidropdown(app.ExcelfilesettingsPanel);
            app.PriceTypeIndexField.Items = {};
            app.PriceTypeIndexField.ValueChangedFcn = createCallbackFcn(app, @updateSettings, true);
            app.PriceTypeIndexField.Tooltip = {'Define name of the field that contains indices of charges (normally ID) for detection of duplicates'};
            app.PriceTypeIndexField.Value = {};
            app.TableindexfieldLabel_2 = uilabel(app.ExcelfilesettingsPanel);
            app.TableindexfieldLabel_2.Text = 'Price type field:';
            app.OutputFilenamePanel = uipanel(app.gui);
            app.OutputFilenamePanel.Title = 'Output filename';
            app.OutputFilename = uieditfield(app.OutputFilenamePanel, 'text');
            app.OutputFilename.ValueChangedFcn = createCallbackFcn(app, @OutputFilenameValueChanged, true);
            app.OutputFilename.Tooltip = {'Define the output directory for results'; ''};
            app.SelectOutputFilenameBtn = uibutton(app.OutputFilenamePanel, 'push');
            app.SelectOutputFilenameBtn.ButtonPushedFcn = createCallbackFcn(app, @SelectOutputFilenameBtnButtonPushed, true);
            app.SelectOutputFilenameBtn.Tooltip = {'Define the output directory for results'};
            app.SelectOutputFilenameBtn.Text = '...';
            app.HelpButton = uibutton(app.gui, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Enable = 'off';
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.gui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = View(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.gui)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gui)
        end
    end
end